classdef tremorLabeling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PHTLabelAppUIFigure       matlab.ui.Figure
        SyncdataButton            matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        FilesTab                  matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        LabelingTab               matlab.ui.container.Tab
        AddchunkButton            matlab.ui.control.Button
        RemovechunkButton         matlab.ui.control.Button
        PHTButton                 matlab.ui.control.Button
        MovementButton            matlab.ui.control.Button
        StaticButton              matlab.ui.control.Button
        ClassifychunkLabel        matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        SavefileButton            matlab.ui.control.Button
        SelectedmeasurementLabel  matlab.ui.control.Label
        emptyLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        measurements % All measurements
        measurement % Selected measurements
        ROI % ROI for labeling
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.measurements = sync_measurement_data("Research");
            N = length(app.measurements);
            
            data = {N};
            
            for i=1:N
                data{i, 1} = app.measurements{i}.fileName;
                data{i, 2} = app.measurements{i}.isLabeled;
            end
            
            app.UITable.Data = data;
            disableDefaultInteractivity(app.UIAxes);
        end

        % Button pushed function: SyncdataButton
        function SyncdataButtonPushed(app, event)
            app.measurements = sync_measurement_data("Research");
            N = length(app.measurements);
            
            data = {N};
            
            for i=1:N
                data{i, 1} = app.measurements{i}.fileName;
                data{i, 2} = app.measurements{i}.isLabeled;
            end
            
            app.UITable.Data = data;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.measurement = app.measurements{event.Indices(1)};
            app.measurement.fileName
            app.emptyLabel.Text = app.measurement.fileName;
            plot(app.UIAxes, [0 size(app.measurement.accData, 1)/100], [0 0], 'color', 'black', 'linewidth', 1);
                
            app.ROI = {};
            
            if isfield(app.measurement, 'chunks')
                for i = 1:length(app.measurement.chunks)
                    chunk = app.measurement.chunks{i};             
                    color = 'green';
                    
                    if chunk.classification == 1
                        color = 'red';
                    elseif chunk.classification == 2
                        color = 'blue'; 
                    else
                        color = 'green'; 
                    end
                    
                    app.ROI{size(app.ROI, 2)+1} = images.roi.Rectangle;
                    app.ROI{end} = [app.ROI{end}, drawrectangle(app.UIAxes, 'Color', color, 'Position', [chunk.startTime, -1, chunk.finishTime - chunk.startTime, 2])];
                end
            end
        end

        % Button pushed function: AddchunkButton
        function AddchunkButtonPushed(app, event)
            app.ROI{size(app.ROI, 2)+1} = images.roi.Rectangle;
            app.ROI{end} = [app.ROI{end}, drawrectangle(app.UIAxes, 'Color', 'green', 'Position', [0, -1, 5, 2])];
        end

        % Button pushed function: PHTButton
        function PHTButtonPushed(app, event)
            app.ROI{end}(2).Color = 'red';
        end

        % Button pushed function: MovementButton
        function MovementButtonPushed(app, event)
            app.ROI{end}(2).Color = 'blue';
        end

        % Button pushed function: StaticButton
        function StaticButtonPushed(app, event)
            app.ROI{end}(2).Color = 'green';
        end

        % Button pushed function: RemovechunkButton
        function RemovechunkButtonPushed(app, event)
            delete(app.ROI{end});
            app.ROI(end) = [];
        end

        % Button pushed function: SavefileButton
        function SavefileButtonPushed(app, event)
            for i = 1:size(app.ROI, 2)
                app.ROI{i}(2).Position
                app.ROI{i}(2).Color
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PHTLabelAppUIFigure = uifigure('Visible', 'off');
            app.PHTLabelAppUIFigure.Name = 'PHT Label App';
            app.SyncdataButton = uibutton(app.PHTLabelAppUIFigure, 'push');
            app.SyncdataButton.ButtonPushedFcn = createCallbackFcn(app, @SyncdataButtonPushed, true);
            app.SyncdataButton.Text = 'Sync data';
            app.TabGroup = uitabgroup(app.PHTLabelAppUIFigure);
            app.FilesTab = uitab(app.TabGroup);
            app.FilesTab.Title = 'Files';
            app.UITable = uitable(app.FilesTab);
            app.UITable.ColumnName = {'Filename'; 'Is labeled'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.LabelingTab = uitab(app.TabGroup);
            app.LabelingTab.Title = 'Labeling';
            app.AddchunkButton = uibutton(app.LabelingTab, 'push');
            app.AddchunkButton.ButtonPushedFcn = createCallbackFcn(app, @AddchunkButtonPushed, true);
            app.AddchunkButton.Text = 'Add chunk';
            app.RemovechunkButton = uibutton(app.LabelingTab, 'push');
            app.RemovechunkButton.ButtonPushedFcn = createCallbackFcn(app, @RemovechunkButtonPushed, true);
            app.RemovechunkButton.Text = 'Remove chunk';
            app.PHTButton = uibutton(app.LabelingTab, 'push');
            app.PHTButton.ButtonPushedFcn = createCallbackFcn(app, @PHTButtonPushed, true);
            app.PHTButton.Text = 'PHT';
            app.MovementButton = uibutton(app.LabelingTab, 'push');
            app.MovementButton.ButtonPushedFcn = createCallbackFcn(app, @MovementButtonPushed, true);
            app.MovementButton.Text = 'Movement';
            app.StaticButton = uibutton(app.LabelingTab, 'push');
            app.StaticButton.ButtonPushedFcn = createCallbackFcn(app, @StaticButtonPushed, true);
            app.StaticButton.Text = 'Static';
            app.ClassifychunkLabel = uilabel(app.LabelingTab);
            app.ClassifychunkLabel.Text = 'Classify chunk:';
            app.UIAxes = uiaxes(app.LabelingTab);
            xlabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Z')
            app.SavefileButton = uibutton(app.PHTLabelAppUIFigure, 'push');
            app.SavefileButton.ButtonPushedFcn = createCallbackFcn(app, @SavefileButtonPushed, true);
            app.SavefileButton.Text = 'Save file';
            app.SelectedmeasurementLabel = uilabel(app.PHTLabelAppUIFigure);
            app.SelectedmeasurementLabel.Text = 'Selected measurement: ';
            app.emptyLabel = uilabel(app.PHTLabelAppUIFigure);
            app.emptyLabel.Text = 'empty';
            % Show the figure after all components are created
            app.PHTLabelAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tremorLabeling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PHTLabelAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PHTLabelAppUIFigure)
        end
    end
end